classdef winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        btn_uiprogressdlg  matlab.ui.control.Button
        btn_uiconfirm      matlab.ui.control.Button
        btn_uialert        matlab.ui.control.Button
        btn_uicredential   matlab.ui.control.Button
        jsBackDoor         matlab.ui.control.HTML
        Label              matlab.ui.control.Label
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function jsBackDoorListener(app, event)
            switch event.HTMLEventName
                case 'credentialDialog'
                    Username = event.HTMLEventData.login;
                    Password = event.HTMLEventData.password;
                    updateScreen(app, Username, Password)

                otherwise
                    error('Unexpected call')
            end
        end

        %-----------------------------------------------------------------%
        function updateScreen(app, Username, Password)
            app.Label.Text = sprintf('Wow!!!\nNow I know that your login is "%s" and your secret password is "%s".', Username, Password);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            appUtil.winPosition(app.UIFigure)
            
            app.jsBackDoor.HTMLSource = appUtil.jsBackDoorHTMLSource();
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoorListener(app, evt);

        end

        % Button pushed function: btn_uialert, btn_uiconfirm, 
        % ...and 2 other components
        function ButtonPushed(app, event)
            
            switch event.Source
                case app.btn_uicredential
                    sendEventToHTMLSource(app.jsBackDoor, 'credentialDialog', struct('UUID', char(matlab.lang.internal.uuid())))
                case app.btn_uialert
                    uialert(app.UIFigure, 'MATLAB Built-in uialert', '', 'Icon', 'info')
                case app.btn_uiconfirm
                    uiconfirm(app.UIFigure, 'MATLAB Built-in uiconfirm', '', 'Icon', 'question');
                case app.btn_uiprogressdlg
                    d = uiprogressdlg(app.UIFigure, 'Message', 'MATLAB Built-in uiprogressdlg', 'Title', '', 'Indeterminate', 'on');
                    pause(3)
                    delete(d)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Hi! '; 'I don''t know your login and password yet. :('};
            app.jsBackDoor = uihtml(app.GridLayout);
            app.btn_uicredential = uibutton(app.GridLayout, 'push');
            app.btn_uicredential.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btn_uicredential.Text = {'Push this button to insert your credentials'; '"uicredential"'};
            app.btn_uialert = uibutton(app.GridLayout, 'push');
            app.btn_uialert.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btn_uialert.Text = {'MATLAB Built-in'; 'uialert'};
            app.btn_uiconfirm = uibutton(app.GridLayout, 'push');
            app.btn_uiconfirm.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btn_uiconfirm.Text = {'MATLAB Built-in'; 'uiconfirm'};
            app.btn_uiprogressdlg = uibutton(app.GridLayout, 'push');
            app.btn_uiprogressdlg.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btn_uiprogressdlg.Text = {'MATLAB Built-in'; 'uiprogressdlg'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winCredential

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end